classdef realtime_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LabelCmd             matlab.ui.control.Label
        ConnectButton        matlab.ui.control.Button
        EEGPortEditField     matlab.ui.control.EditField
        portEditFieldLabel   matlab.ui.control.Label
        EEGIPEditField       matlab.ui.control.EditField
        EEGIPEditFieldLabel  matlab.ui.control.Label
        LampConnect          matlab.ui.control.Lamp
        LampDatastream       matlab.ui.control.Lamp
        StreamSwitch         matlab.ui.control.ToggleSwitch
        StreamSwitchLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        rtEEG_obj % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rtEEG_obj.ip=app.EEGIPEditField.Value;
            app.rtEEG_obj.port=app.EEGPortEditField.Value;
            app.rtEEG_obj.updObj=[];

            app.LampConnect.Color='r';
            app.LampDatastream.Color='r';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(strcmp(app.ConnectButton.Text,'Connect'))
                %try to connect to the EEG
                app.rtEEG_obj.ip=app.EEGIPEditField.Value;
                app.rtEEG_obj.port=app.EEGPortEditField.Value;

                try
                    app.rtEEG_obj.udpObj=udpport('LocalHost',app.EEGIPEditField.Value,'LocalPort',str2num(app.EEGPortEditField.Value))
                    flush(app.rtEEG_obj.udpObj);
                    app.LampConnect.Color='g';

                    app.ConnectButton.Text='Disconnect';

                catch
                    fprintf('Connect error!\n');
                    return;
                end;
            else
                try
                    delete(app.rtEEG_obj.udpObj);
                    app.LampConnect.Color='r';

                    app.ConnectButton.Text='Connect';
                catch
                    fprintf('Disconnect error!\n');
                    return;
                end;
            end;
        end

        % Value changed function: StreamSwitch
        function StreamSwitchValueChanged(app, event)
            value = app.StreamSwitch.Value;

            while true
                try
                    %rteeg_decode;

                     tmp= read(app.rtEEG_obj.udpObj, app.rtEEG_obj.udpObj.NumBytesAvailable);
                     %app.LabelCmd.Text=sprintf('[%04d] bytes...',length(tmp));
                     buffer=rteeg_decode(tmp);

                     if(isfield(buffer,'frameType'))
                         if(buffer.frameType==2)

                            app.LabelCmd.Text=sprintf('[%04d] bytes; [%012d] sequence number;\n [%4d] ch; [%06d] sample bundles; [%s] data matrix...',length(tmp),buffer.packetSeqNo, buffer.numChannels, buffer.numSampleBundles,num2str(size(buffer.sample)));
                         end;
                     end;

                     if(strcmp(get(app.StreamSwitch,'Value'),'Off'))
                         flush(app.rtEEG_obj.udpObj);

                         break;
                     end;
                     pause(0.01)
                     app.LampDatastream.Color='g';

                catch
                    break;
                end;

            end;

            app.LampDatastream.Color='r';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StreamSwitchLabel = uilabel(app.GridLayout);
            app.StreamSwitchLabel.Text = 'Stream';
            app.StreamSwitch = uiswitch(app.GridLayout, 'toggle');
            app.StreamSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamSwitchValueChanged, true);
            app.LampDatastream = uilamp(app.GridLayout);
            app.LampConnect = uilamp(app.GridLayout);
            app.EEGIPEditFieldLabel = uilabel(app.GridLayout);
            app.EEGIPEditFieldLabel.Text = 'EEG  IP';
            app.EEGIPEditField = uieditfield(app.GridLayout, 'text');
            app.EEGIPEditField.Value = '192.168.200.220';
            app.portEditFieldLabel = uilabel(app.GridLayout);
            app.portEditFieldLabel.Text = 'port';
            app.EEGPortEditField = uieditfield(app.GridLayout, 'text');
            app.EEGPortEditField.Value = '50000';
            app.ConnectButton = uibutton(app.GridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LabelCmd = uilabel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realtime_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end